classdef ParetoInteractivity2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TimestepEditField       matlab.ui.control.NumericEditField
        TimestepEditFieldLabel  matlab.ui.control.Label
        AgentDropDown           matlab.ui.control.DropDown
        AgentDropDownLabel      matlab.ui.control.Label
        ShowTrajectoryButton    matlab.ui.control.Button
        ValueTable              matlab.ui.control.Table
        metricBox               matlab.ui.control.ListBox
        MetricsLabel            matlab.ui.control.Label
        unnormnormButton        matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        Plot2d                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        agentObj; % calling agent object
        figureHandle; % handle of uifigure
        front; % Pareto front
        normalizedFront; % normalized Pareto front
        frontToPlot; % stores Pareto front or normalized front
        J_nadir;
        J_utopia;
        chosenIdx = []; % Idx selected for further evalueation
        costFcnNames;
        % boolean values to decide whether to plot metric selection
        plotCUP = true;
        plotRoC = false;
        plotAEP = false;
        plotFH = false;
        idxCUP;
        idxRoC;
        idxAEP;
        idxFH = [];
        doFH = false;
        livePlotStatus = false;
        enablePlotsStatus = true;
        useSim = true;
        simObj;
        timeStep;
        paretoParameters;
        history;
    end
    
    properties (Access = public)
        % flag for hiding and showing the window
        waitingFlag = false;
    end
    
    methods (Access = public)
        
        function fillApp(app)
            % set all plotting related values like labels
            if ~app.useSim
                app.front = app.agentObj.controller.status.front;
            end
            setNormalizedFront(app);
            app.frontToPlot = app.normalizedFront;
            app.costFcnNames = fieldnames(app.agentObj.controller.costFunctionIndexes);
            app.Plot2d.XLabel.String = app.costFcnNames{1};
            app.Plot2d.YLabel.String = app.costFcnNames{2};
            app.Plot2d.Title.String = app.agentObj.name;
            if ~isempty(app.timeStep)
                app.idxCUP = ParetoController.selectCUP(app.agentObj.history.pareto.fronts{app.timeStep});
                app.idxRoC = ParetoController.selectRoC(app.agentObj.history.pareto.fronts{app.timeStep});
                app.idxAEP = ParetoController.selectAEP(app.agentObj.history.pareto.fronts{app.timeStep});
                % add custom metric to the set of metrics
                if isa(app.agentObj.controller.config.metricFunction, 'function_handle')
                    app.metricBox.Items = [app.metricBox.Items, {getFunName(app.agentObj.controller.config.metricFunction)}];
                    app.idxFH = app.agentObj.controller.config.metricFunction(app.agentObj.history.pareto.fronts{app.timeStep}, app.agentObj.controller);
                    app.doFH = true;
                end
            else
                app.idxCUP = ParetoController.selectCUP(app.front);
                app.idxRoC = ParetoController.selectRoC(app.front);
                app.idxAEP = ParetoController.selectAEP(app.front);
                if isa(app.agentObj.controller.config.metricFunction, 'function_handle')
                    app.metricBox.Items = [app.metricBox.Items, {getFunName(app.agentObj.controller.config.metricFunction)}];
                    app.idxFH = app.agentObj.controller.config.metricFunction(app.front, app.agentObj.controller);
                    app.doFH = true;
                end
            end
            app.plotFront;
        end
        
        function fillValueTable(app)
            app.ValueTable.Data = [app.front(app.chosenIdx,:),app.normalizedFront(app.chosenIdx,:)];
            if app.plotCUP
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxCUP,:),app.normalizedFront(app.idxCUP,:)];
            end
            if app.plotRoC
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxRoC,:),app.normalizedFront(app.idxRoC,:)];
            end
            if app.plotAEP
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxAEP,:),app.normalizedFront(app.idxAEP,:)];
            end
            if app.plotFH
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxFH,:),app.normalizedFront(app.idxFH,:)];
            end
        end
        
        function setValueTableData(app)
            colName = {};
            rowName = {};
            vText = "Obj. "+(1:2);
            nText = "normed Obj. "+(1:2);
            
            for i=1:2
                colName = [colName, vText{i}];
            end
            for j=1:2
                colName = [colName, nText{j}];
            end
            
            rowName = [rowName, 'selected'];
            
            if app.plotCUP
                rowName = [rowName, 'CUP'];
            end
            if app.plotRoC
                rowName = [rowName, 'RoC'];
            end
            if app.plotAEP
                rowName = [rowName, 'AEP'];
            end
            if app.plotFH
                rowName = [rowName, getFunName(app.agentObj.controller.config.metricFunction)];
            end
            
            app.ValueTable.RowName = rowName;
            app.ValueTable.ColumnName = colName;
        end
        
        function plotFront(app)
            % plot the Pareto front, the metric selections and set the legend
            legendText = {};
            legendHeight = 0;
            hold(app.Plot2d,'off');
            
            if app.plotCUP
                plot(app.Plot2d,app.frontToPlot(app.idxCUP,1),app.frontToPlot(app.idxCUP,2),'ro','MarkerSize',10);
                hold(app.Plot2d,'on');
                legendText = [legendText, 'CUP'];
                legendHeight = legendHeight + 0.05;
            end
            if app.plotRoC
                plot(app.Plot2d,app.frontToPlot(app.idxRoC,1),app.frontToPlot(app.idxRoC,2),'c^','MarkerSize',10);
                hold(app.Plot2d,'on');
                legendText = [legendText, 'RoC'];
                legendHeight = legendHeight + 0.05;
            end
            if app.plotAEP
                plot(app.Plot2d,app.frontToPlot(app.idxAEP,1),app.frontToPlot(app.idxAEP,2),'ks','MarkerSize',10);
                hold(app.Plot2d,'on');
                legendText = [legendText, 'AEP'];
                legendHeight = legendHeight + 0.05;
            end
            if app.plotFH
                plot(app.Plot2d,app.frontToPlot(app.idxFH,1),app.frontToPlot(app.idxFH,2),'o','Color',[1.00,0.41,0.16]);
                hold(app.Plot2d,'on');
                legendText = [legendText, {getFunName(app.agentObj.controller.config.metricFunction)}];
                legendHeight = legendHeight + 0.05;
            end
            if ~isempty(app.chosenIdx)
                plot(app.Plot2d,app.frontToPlot(app.chosenIdx,1),app.frontToPlot(app.chosenIdx,2),'gd','MarkerSize',7.5,'LineWidth',1.5);
                hold(app.Plot2d,'on');
                legendText = [legendText, 'selected'];
                legendHeight = legendHeight + 0.05;
            end
            
            plot(app.Plot2d,app.frontToPlot(:,1),app.frontToPlot(:,2),'bx');
            p = scatter(app.Plot2d,app.frontToPlot(:,1),app.frontToPlot(:,2),'filled');
            p.MarkerEdgeAlpha = 0;
            p.MarkerFaceAlpha = 0;
            
            l = legend(app.Plot2d,legendText);
            l.Position(2) = 0.95-legendHeight;
            l.Position(4) = legendHeight;
            
            % set function to enable clicking a point
            p.ButtonDownFcn = @(src1, src2) mouseClick(app, src1, src2);
        end
        
        function showScreen(app)
            app.fillApp;
            app.UIFigure.WindowState = 'normal';
            app.UIFigure.Visible = 'on';
            app.plotFront;
        end
        
        function minimizeScreen(app)
            app.UIFigure.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simObj, agentObj, inSimulation)
            
            if ~isa(simObj,'Simulation')
                error('The first input needs to be of type Simulation.')
            end
            
            if nargin == 4 % if called during the simulation
                
                if ~isa(agentObj,'Agent')
                    error('The second input needs to be of type Agent.')
                end
                
                app.agentObj = agentObj;
                app.useSim = false;
                app.AgentDropDown.Visible = false;
                app.AgentDropDownLabel.Visible = false;
                app.TimestepEditField.Visible = false;
                app.TimestepEditFieldLabel.Visible = false;
                app.timeStep = [];
                
            elseif nargin <= 3 % if called for simulation evaluation
                
                app.simObj = simObj;
                app.useSim = true;
                loadFullHistory(app);
                agentNames = fieldnames(simObj.agents);
                for agentsIdx = 1:length(agentNames)
                    if (length(simObj.agents.(agentNames{agentsIdx}).controller.costFunctions) ~= 2) || ~isequal(simObj.agents.(agentNames{agentsIdx}).controller.type,'pareto')
                        agentNames(agentsIdx,:) = [];
                    end
                end
                app.AgentDropDown.Items = agentNames;
                if nargin == 2
                    app.AgentDropDown.Value = app.AgentDropDown.Items{1};
                else
                    app.AgentDropDown.Value = agentObj.name;
                end
                app.agentObj = simObj.agents.(app.AgentDropDown.Value);
                app.timeStep = app.TimestepEditField.Value + 1;
                app.agentObj.controller.status.inputs = cell(1,size(app.agentObj.history.pareto.paretoParameters{app.timeStep},1));
                app.front = app.agentObj.history.pareto.fronts{1};
                app.fillApp;
            end
            
            app.setValueTableData;
            app.livePlotStatus = app.agentObj.simulation.config.livePlot;
            app.enablePlotsStatus = app.agentObj.simulation.config.enablePlots;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % write chosenIdx to interactivityIdx for further evaluation and hide window
            if isempty(app.chosenIdx)
                app.agentObj.controller.interactivityIdx = app.idxCUP;
            else
                app.agentObj.controller.interactivityIdx = app.chosenIdx;
            end
            app.waitingFlag = ~app.waitingFlag;
            app.agentObj.simulation.config.livePlot = app.livePlotStatus;
            app.agentObj.simulation.config.enablePlots = app.enablePlotsStatus;
            app.minimizeScreen;
            hold(app.Plot2d,'off');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: metricBox
        function metricBoxValueChanged(app, event)
            value = app.metricBox.Value;
            if any(ismember(value,'CUP'))
                app.plotCUP = true;
            else
                app.plotCUP = false;
            end
            if any(ismember(value,'RoC'))
                app.plotRoC = true;
            else
                app.plotRoC = false;
            end
            if any(ismember(value,'AEP'))
                app.plotAEP = true;
            else
                app.plotAEP = false;
            end
            if app.doFH  && any(ismember(value,app.metricBox.Items{end}))
                app.plotFH = true;
            else
                app.plotFH = false;
            end
            app.setValueTableData;
            app.fillValueTable;
            setNormalizedFront(app);
            app.fillApp;
            app.plotFront;
        end

        % Button pushed function: unnormnormButton
        function unnormnormButtonPushed(app, event)
            if app.frontToPlot == app.front
                app.frontToPlot = app.normalizedFront;
            elseif app.frontToPlot == app.normalizedFront
                app.frontToPlot = app.front;
            end
            app.plotFront;
        end

        % Button pushed function: ShowTrajectoryButton
        function ShowTrajectoryButtonPushed(app, event)
            % display figures and the predicted trajectory
            loadDataToStatus(app);
            app.agentObj.updatePredictions();
            
            app.agentObj.simulation.config.livePlot = true;
            app.agentObj.simulation.config.enablePlots = true;
            
            app.agentObj.simulation.updatePlots(app.agentObj);
            app.agentObj.status.xPred = [];
            app.agentObj.status.evalPred = [];
            app.agentObj.status.costsPred = [];
        end

        % Value changed function: AgentDropDown
        function AgentDropDownValueChanged(app, event)
            value = app.AgentDropDown.Value;
            app.chosenIdx = [];
            app.agentObj = app.simObj.agents.(value);
            app.front = app.agentObj.history.pareto.fronts{app.timeStep};
            setNormalizedFront(app);
            app.fillApp();
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            value = app.TimestepEditField.Value;
            app.agentObj.controller.status.inputs = cell(1,size(app.agentObj.history.pareto.paretoParameters{value+1},1));
            app.timeStep = value + 1;
            app.chosenIdx = [];
            app.front = app.agentObj.history.pareto.fronts{value+1};
            setNormalizedFront(app);
            app.fillApp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Plot2d = uiaxes(app.UIFigure);
            title(app.Plot2d, 'Title')
            xlabel(app.Plot2d, 'X')
            ylabel(app.Plot2d, 'Y')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.unnormnormButton = uibutton(app.UIFigure, 'push');
            app.unnormnormButton.ButtonPushedFcn = createCallbackFcn(app, @unnormnormButtonPushed, true);
            app.unnormnormButton.Text = 'unnorm/norm';
            app.MetricsLabel = uilabel(app.UIFigure);
            app.MetricsLabel.Text = 'Metrics';
            app.metricBox = uilistbox(app.UIFigure);
            app.metricBox.Items = {'CUP', 'RoC', 'AEP'};
            app.metricBox.Multiselect = 'on';
            app.metricBox.ValueChangedFcn = createCallbackFcn(app, @metricBoxValueChanged, true);
            app.metricBox.Value = {'CUP'};
            app.ValueTable = uitable(app.UIFigure);
            app.ValueTable.ColumnName = {''; ''; ''};
            app.ValueTable.RowName = {'''seleted'''; '''metric'''};
            app.ShowTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.ShowTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTrajectoryButtonPushed, true);
            app.ShowTrajectoryButton.Text = 'Show Trajectory';
            app.AgentDropDownLabel = uilabel(app.UIFigure);
            app.AgentDropDownLabel.Text = 'Agent';
            app.AgentDropDown = uidropdown(app.UIFigure);
            app.AgentDropDown.Items = {'Option 1', ''};
            app.AgentDropDown.ValueChangedFcn = createCallbackFcn(app, @AgentDropDownValueChanged, true);
            app.TimestepEditFieldLabel = uilabel(app.UIFigure);
            app.TimestepEditFieldLabel.Text = 'Timestep';
            app.TimestepEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimestepEditField.Limits = [0 Inf];
            app.TimestepEditField.RoundFractionalValues = 'on';
            app.TimestepEditField.ValueDisplayFormat = '%.0f';
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParetoInteractivity2D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end