classdef ParetoInteractivity3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TimestepEditField       matlab.ui.control.NumericEditField
        TimestepEditFieldLabel  matlab.ui.control.Label
        AgentDropDown           matlab.ui.control.DropDown
        AgentDropDownLabel      matlab.ui.control.Label
        ShowTrajectoryButton    matlab.ui.control.Button
        ValueTable              matlab.ui.control.Table
        metricBox               matlab.ui.control.ListBox
        MetricsLabel            matlab.ui.control.Label
        unnormnormButton        matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        PlotProjection          matlab.ui.control.UIAxes
        PlotSpider              matlab.ui.control.UIAxes
        Plot3d                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        agentObj; % calling agent object
        figureHandle; % handle of uifigure
        front; % Pareto front
        normalizedFront;
        frontToPlot; % stores Pareto front or normalized front
        projectedFront;
        projectedColor;
        J_nadir;
        J_utopia;
        chosenIdx = []; % Description
        costFcnNames;
        % boolean values to decide whether to plot metric selection
        plotCUP = true;
        plotRoC = false;
        plotAEP = false;
        plotFH = false;
        idxCUP;
        idxRoC;
        idxAEP;
        idxFH = [];
        doFH = false;
        plot3DLastView = [];
        livePlotStatus = false;
        enablePlotsStatus = true;
        useSim = true;
        simObj
        timeStep;
        paretoParameters;
        history;
    end
    
    properties (Access = public)
        % flag for hiding and showing the window
        waitingFlag = false;
    end
    
    methods (Access = private)
        
        function mouseClick(app,~,src2)
            [~,selectedIdx] = min(vecnorm(app.projectedFront-src2.IntersectionPoint(:,1:2),2,2));
            if selectedIdx == app.chosenIdx
                app.chosenIdx = [];
            else
                app.chosenIdx = selectedIdx;
            end
            app.plot3DFront;
            app.plotProjectedFront;
            app.plotSpiderPlot;
            app.fillValueTable;
        end
        
        function plot3DFront(app)
            % plot the Pareto front, the metric selections and set the legend
            app.plot3DLastView = app.Plot3d.View;
            hold(app.Plot3d,'off');
            
            if app.plotCUP
                plot3(app.Plot3d,app.frontToPlot(app.idxCUP,1),app.frontToPlot(app.idxCUP,2),app.frontToPlot(app.idxCUP,3),'ro','MarkerSize',10);
                hold(app.Plot3d,'on');
            end
            if app.plotRoC
                plot3(app.Plot3d,app.frontToPlot(app.idxRoC,1),app.frontToPlot(app.idxRoC,2),app.frontToPlot(app.idxRoC,3),'c^','MarkerSize',10);
                hold(app.Plot3d,'on');
            end
            if app.plotAEP
                plot3(app.Plot3d,app.frontToPlot(app.idxAEP,1),app.frontToPlot(app.idxAEP,2),app.frontToPlot(app.idxAEP,3),'ks','MarkerSize',10);
                hold(app.Plot3d,'on');
            end
            if app.plotFH
                plot3(app.Plot3d,app.frontToPlot(app.idxFH,1),app.frontToPlot(app.idxFH,2),app.frontToPlot(app.idxFH,3),'o','Color',[1.00,0.41,0.16]);
                hold(app.Plot3d,'on');
            end
            if ~isempty(app.chosenIdx)
                plot3(app.Plot3d,app.frontToPlot(app.chosenIdx,1),app.frontToPlot(app.chosenIdx,2),app.frontToPlot(app.chosenIdx,3),'gd','MarkerSize',7.5,'LineWidth',1.5);
                hold(app.Plot3d,'on');
            end
            
            plot3(app.Plot3d,app.frontToPlot(:,1),app.frontToPlot(:,2),app.frontToPlot(:,3),'bx');
            if isempty(app.plot3DLastView)
                app.plot3DLastView = app.Plot3d.View;
            end
            app.Plot3d.View = app.plot3DLastView;
        end
        
        function plotProjectedFront(app)
            % plot the projected Pareto front, the metric selections and set the legend
            hold(app.PlotProjection,'off')
            legendText = {};
            legendHeight = 0;
            hold(app.PlotProjection,'off');
            
            if app.plotCUP
                plot(app.PlotProjection,app.projectedFront(app.idxCUP,1),app.projectedFront(app.idxCUP,2),'ro','MarkerSize',10);
                hold(app.PlotProjection,'on');
                legendText = [legendText, 'CUP'];
                legendHeight = legendHeight + 0.05;
            end
            if app.plotRoC
                plot(app.PlotProjection,app.projectedFront(app.idxRoC,1),app.projectedFront(app.idxRoC,2),'c^','MarkerSize',10);
                hold(app.PlotProjection,'on');
                legendText = [legendText, 'RoC'];
                legendHeight = legendHeight + 0.05;
            end
            if app.plotAEP
                plot(app.PlotProjection,app.projectedFront(app.idxAEP,1),app.projectedFront(app.idxAEP,2),'ks','MarkerSize',10);
                hold(app.PlotProjection,'on');
                legendText = [legendText, 'AEP'];
                legendHeight = legendHeight + 0.05;
            end
            if app.plotFH
                plot(app.PlotProjection,app.projectedFront(app.idxFH,1),app.projectedFront(app.idxFH,2),'o','Color',[1.00,0.41,0.16]);
                hold(app.PlotProjection,'on');
                legendText = [legendText, {getFunName(app.agentObj.controller.config.metricFunction)}];
                legendHeight = legendHeight + 0.05;
            end
            if ~isempty(app.chosenIdx)
                plot(app.PlotProjection,app.projectedFront(app.chosenIdx,1),app.projectedFront(app.chosenIdx,2),'gd','MarkerSize',7.5,'LineWidth',1.5);
                hold(app.PlotProjection,'on');
                legendText = [legendText, 'selected'];
                legendHeight = legendHeight + 0.05;
            end
            
            scatter(app.PlotProjection, app.projectedFront(:,1), app.projectedFront(:,2),30,app.colormapping(app.projectedColor),'Marker','x')
            hold(app.PlotProjection,'on');
            if ~isempty(app.chosenIdx)
                plot(app.PlotProjection,app.projectedFront(app.chosenIdx,1),app.projectedFront(app.chosenIdx,2),'gd','MarkerSize',7.5,'LineWidth',1.5);
                hold(app.PlotProjection,'on');
            end
            p = scatter(app.PlotProjection, app.projectedFront(:,1), app.projectedFront(:,2),'filled');
            p.MarkerEdgeAlpha = 0;
            p.MarkerFaceAlpha = 0;
            
            l = legend(app.PlotProjection,legendText);
            l.Position(1) = 0.1;
            l.Position(2) = 0.925-legendHeight;
            l.Position(4) = legendHeight;
            
            p.ButtonDownFcn = @app.mouseClick;
            
            colormap(app.PlotProjection,app.colormapping(linspace(0,1,256)'))
            colorbar(app.PlotProjection,'TickLabels',linspace(1,0,11))
        end
        
        
        function plotSpiderPlot(app)
            % plot the spider plot for the selected Pareto optimal point and selected metrics
            SpiderPoints = [];
            legendText = {};
            if app.plotCUP
                SpiderPoints = [SpiderPoints;app.normalizedFront(app.idxCUP,:)];
                legendText = [legendText, 'CUP'];
            end
            if app.plotRoC
                SpiderPoints = [SpiderPoints;app.normalizedFront(app.idxRoC,:)];
                legendText = [legendText, 'RoC'];
            end
            if app.plotAEP
                SpiderPoints = [SpiderPoints;app.normalizedFront(app.idxAEP,:)];
                legendText = [legendText, 'AEP'];
            end
            if app.plotFH
                SpiderPoints = [SpiderPoints;app.normalizedFront(app.idxFH,:)];
                legendText = [legendText, 'FH'];
            end
            if ~isempty(app.chosenIdx)
                SpiderPoints = [SpiderPoints;app.normalizedFront(app.chosenIdx,:)];
                legendText = [legendText, 'Selected'];
            end
            uispider_plot(app.PlotSpider,SpiderPoints,'AxesLimits',[zeros(1,3);ones(1,3)],'AxesFontSize',15,'AxesLabels', app.costFcnNames)
            legend(app.PlotSpider,legendText);
        end
        
        function fillApp(app)
             % set all plotting related values like labels
            if ~app.useSim
                app.front = app.agentObj.controller.status.front;
            end
            
            setNormalizedFront(app);
            app.getProjection;
            app.frontToPlot = app.normalizedFront;
            app.costFcnNames = fieldnames(app.agentObj.controller.costFunctionIndexes);
            app.Plot3d.XLabel.String = app.costFcnNames{1};
            app.Plot3d.YLabel.String = app.costFcnNames{2};
            app.Plot3d.ZLabel.String = app.costFcnNames{3};
            app.Plot3d.Title.String = app.agentObj.name;
            
            if ~isempty(app.timeStep)
                app.idxCUP = ParetoController.selectCUP(app.agentObj.history.pareto.fronts{app.timestep});
                app.idxRoC = ParetoController.selectRoC(app.agentObj.history.pareto.fronts{app.timestep});
                app.idxAEP = ParetoController.selectAEP(app.agentObj.history.pareto.fronts{app.timestep});
                if isa(app.agentObj.controller.config.metricFunction, 'function_handle')
                    app.metricBox.Items = [app.metricBox.Items, {getFunName(app.agentObj.controller.config.metricFunction)}];
                    app.idxFH = app.agentObj.controller.config.metricFunction(app.agentObj.history.pareto.fronts{app.timestep}, app.agentObj.controller);
                    app.doFH = 1;
                end
            else
                app.idxCUP = ParetoController.selectCUP(app.front);
                app.idxRoC = ParetoController.selectRoC(app.front);
                app.idxAEP = ParetoController.selectAEP(app.front);
                if isa(app.agentObj.controller.config.metricFunction, 'function_handle')
                    app.metricBox.Items = [app.metricBox.Items, {getFunName(app.agentObj.controller.config.metricFunction)}];
                    app.idxFH = app.agentObj.controller.config.metricFunction(app.front, app.agentObj.controller);
                    app.doFH = true;
                end
            end
            app.plot3DFront;
            app.plotProjectedFront
            app.plotSpiderPlot
        end
        
        function fillValueTable(app)
            app.ValueTable.Data = [app.front(app.chosenIdx,:),app.normalizedFront(app.chosenIdx,:)];
            if app.plotCUP
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxCUP,:),app.normalizedFront(app.idxCUP,:)];
            end
            if app.plotRoC
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxRoC,:),app.normalizedFront(app.idxRoC,:)];
            end
            if app.plotAEP
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxAEP,:),app.normalizedFront(app.idxAEP,:)];
            end
            if app.plotFH
                app.ValueTable.Data = [app.ValueTable.Data; app.front(app.idxFH,:),app.normalizedFront(app.idxFH,:)];
            end
        end
        
        function setValueTableData(app)
            colName = {};
            rowName = {};
            vText = "Obj. "+(1:3);
            nText = "normed Obj. "+(1:3);
            
            for i=1:3
                colName = [colName, vText{i}];
            end
            for j=1:3
                colName = [colName, nText{j}];
            end
            
            rowName = [rowName, 'selected'];
            
            if app.plotCUP
                rowName = [rowName, 'CUP'];
            end
            if app.plotRoC
                rowName = [rowName, 'RoC'];
            end
            if app.plotAEP
                rowName = [rowName, 'AEP'];
            end
            if app.plotFH
                rowName = [rowName, getFunName(app.agentObj.controller.config.metricFunction)];
            end
            
            app.ValueTable.RowName = rowName;
            app.ValueTable.ColumnName = colName;
        end
        
        function getProjection(app)
            [app.projectedFront,distance] = paretoFrontProjection(app.normalizedFront);
            app.projectedColor = abs((distance-min(distance))/(max(distance)-min(distance)));
        end
        
        function cmap = colormapping(~,normedValues)
            cmap = [normedValues, 1-normedValues, 4*(normedValues-normedValues.^2)];
        end
        
    end
    
    methods (Access = public)
        
        function showScreen(app)
            app.fillApp;
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.Visible = 'on';
        end
        
        function minimizeScreen(app)
            app.UIFigure.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simObj, agentObj, inSimulation)
            
            if ~isa(simObj,'Simulation')
                error('The first input needs to be of type Simulation.')
            end
            
            if nargin == 4 % if called during the simulation
                
                if ~isa(agentObj,'Agent')
                    error('The second input needs to be of type Agent.')
                end
                
                app.agentObj = agentObj;
                app.useSim = false;
                app.AgentDropDown.Visible = false;
                app.AgentDropDownLabel.Visible = false;
                app.TimestepEditField.Visible = false;
                app.TimestepEditFieldLabel.Visible = false;
                app.timeStep = [];
                
            elseif nargin <= 3 % if called for simulation evaluation
                
                app.simObj = simObj;
                app.useSim = true;
                loadFullHistory(app);
                agentNames = fieldnames(simObj.agents);
                for agentsIdx = 1:length(agentNames)
                    if (length(simObj.agents.(agentNames{agentsIdx}).controller.costFunctions) ~= 2) || ~isequal(simObj.agents.(agentNames{agentsIdx}).controller.type,'pareto')
                        agentNames(agentsIdx,:) = [];
                    end
                end
                app.AgentDropDown.Items = agentNames;
                if nargin == 2
                    app.AgentDropDown.Value = app.AgentDropDown.Items{1};
                else
                    app.AgentDropDown.Value = agentObj.name;
                end
                app.agentObj = simObj.agents.(app.AgentDropDown.Value);
                app.timeStep = app.TimestepEditField.Value + 1;
                app.agentObj.controller.status.inputs = cell(1,size(app.agentObj.history.pareto.paretoParameters{app.timeStep},1));
                app.front = app.agentObj.history.pareto.fronts{1};
                app.fillApp;
            end
            
            app.setValueTableData;
            app.livePlotStatus = app.agentObj.simulation.config.livePlot;
            app.enablePlotsStatus = app.agentObj.simulation.config.enablePlots;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % write chosenIdx to interactivityIdx for further evaluation and hide window
            if isempty(app.chosenIdx)
                app.agentObj.controller.interactivityIdx = app.idxCUP;
            else
                app.agentObj.controller.interactivityIdx = app.chosenIdx;
            end
            app.waitingFlag = ~app.waitingFlag;
            app.agentObj.simulation.config.livePlot = app.livePlotStatus;
            app.agentObj.simulation.config.enablePlots = app.enablePlotsStatus;
            app.minimizeScreen;
            hold(app.Plot3d,'off');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: metricBox
        function metricBoxValueChanged(app, event)
            value = app.metricBox.Value;
            if any(ismember(value,'CUP'))
                app.plotCUP = true;
            else
                app.plotCUP = false;
            end
            if any(ismember(value,'RoC'))
                app.plotRoC = true;
            else
                app.plotRoC = false;
            end
            if any(ismember(value,'AEP'))
                app.plotAEP = true;
            else
                app.plotAEP = false;
            end
            if app.doFH  && any(ismember(value,app.metricBox.Items{end}))
                app.plotFH = true;
            else
                app.plotFH = false;
            end
            app.setValueTableData;
            app.fillValueTable;
            setNormalizedFront(app);
            app.fillApp;
            app.plot3DFront;
            app.plotProjectedFront;
            app.plotSpiderPlot;
        end

        % Button pushed function: unnormnormButton
        function unnormnormButtonPushed(app, event)
            if app.frontToPlot == app.front
                app.frontToPlot = app.normalizedFront;
            elseif app.frontToPlot == app.normalizedFront
                app.frontToPlot = app.front;
            end
            app.plot3DFront;
        end

        % Button pushed function: ShowTrajectoryButton
        function ShowTrajectoryButtonPushed(app, event)
            % display figures and the predicted trajectory
            loadDataToStatus(app);
            app.agentObj.updatePredictions();
            
            app.agentObj.simulation.config.livePlot = true;
            app.agentObj.simulation.config.enablePlots = true;
            
            app.agentObj.simulation.updatePlots(app.agentObj);
            app.agentObj.status.xPred = [];
            app.agentObj.status.evalPred = [];
            app.agentObj.status.costsPred = [];
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            value = app.TimestepEditField.Value;
            app.timeStep = value;
            app.chosenIdx = [];
            app.front = app.agentObj.history.pareto.fronts{value};
            setNormalizedFront(app);
            app.fillApp();
        end

        % Value changed function: AgentDropDown
        function AgentDropDownValueChanged(app, event)
            app.chosenIdx = [];
            app.agentObj = app.simObj.agents.(app.AgentDropDown.Value);
            app.front = app.agentObj.history.pareto.fronts{app.timeStep};
            setNormalizedFront(app);
            app.fillApp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Plot3d = uiaxes(app.UIFigure);
            title(app.Plot3d, 'Title')
            xlabel(app.Plot3d, 'X')
            ylabel(app.Plot3d, 'Y')
            app.PlotSpider = uiaxes(app.UIFigure);
            title(app.PlotSpider, 'Comparison as Radar Plot')
            app.PlotProjection = uiaxes(app.UIFigure);
            title(app.PlotProjection, 'Projection to Boundary Plane')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.unnormnormButton = uibutton(app.UIFigure, 'push');
            app.unnormnormButton.ButtonPushedFcn = createCallbackFcn(app, @unnormnormButtonPushed, true);
            app.unnormnormButton.Text = 'unnorm/norm';
            app.MetricsLabel = uilabel(app.UIFigure);
            app.MetricsLabel.Text = 'Metrics';
            app.metricBox = uilistbox(app.UIFigure);
            app.metricBox.Items = {'CUP', 'RoC', 'AEP'};
            app.metricBox.Multiselect = 'on';
            app.metricBox.ValueChangedFcn = createCallbackFcn(app, @metricBoxValueChanged, true);
            app.metricBox.Value = {'CUP'};
            app.ValueTable = uitable(app.UIFigure);
            app.ValueTable.ColumnName = {''; ''; ''};
            app.ValueTable.RowName = {'''seleted'''; '''metric'''};
            app.ShowTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.ShowTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTrajectoryButtonPushed, true);
            app.ShowTrajectoryButton.Text = 'Show Trajectory';
            app.AgentDropDownLabel = uilabel(app.UIFigure);
            app.AgentDropDownLabel.Text = 'Agent';
            app.AgentDropDown = uidropdown(app.UIFigure);
            app.AgentDropDown.Items = {'Option 1', ''};
            app.AgentDropDown.ValueChangedFcn = createCallbackFcn(app, @AgentDropDownValueChanged, true);
            app.TimestepEditFieldLabel = uilabel(app.UIFigure);
            app.TimestepEditFieldLabel.Text = 'Timestep';
            app.TimestepEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimestepEditField.Limits = [0 Inf];
            app.TimestepEditField.RoundFractionalValues = 'on';
            app.TimestepEditField.ValueDisplayFormat = '%.0f';
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParetoInteractivity3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end